classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PlotFeatures           matlab.ui.control.Label
        YFeatureLabel          matlab.ui.control.Label
        NextButton             matlab.ui.control.Button
        PreviousButton         matlab.ui.control.Button
        ModeSwitch             matlab.ui.control.Switch
        SimulationProgress     simulink.ui.control.SimulationProgress
        ImageDisp              matlab.ui.control.Image
        ExportData_Button      matlab.ui.control.Button
        Credit                 matlab.ui.control.Hyperlink
        XDropDown              matlab.ui.control.DropDown
        FeatureLabel           matlab.ui.control.Label
        YDropDown              matlab.ui.control.DropDown
        Number_of_Cells_Field  matlab.ui.control.NumericEditField
        Number_of_Cells_Text   matlab.ui.control.Label
        Segment_Button         matlab.ui.control.Button
        Help_Button            matlab.ui.control.Button
        Settings_Button        matlab.ui.control.Button
        UploadImagesButton     matlab.ui.control.Button
        HistogramAxes          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
    Images; % Stores the uploaded image
    CurrentImageIndex = 1;
    SegmentedImages; % Stores the segmented image
    net; % Stores the pre-trained CNN network
    Masks;
    Boxes;
    Labels;
    Scores;
    AnalysisData;
    end
    
    methods (Access = private)
        
        function [] = fixIMGDisplay(app, Image, ImageDisp) %function to display image file without labels properly
            %title(ImageAxes, []);
            %xlabel(ImageAxes, []);
            %ylabel(ImageAxes, []);
            %ImageAxes.XAxis.TickLabels = {};
            %ImageAxes.YAxis.TickLabels = {};
            %ImageAxes.PositionConstraint="outerposition";
            %ImageAxes.InnerPosition=ImageAxes.OuterPosition
    %         I = imshow(Image, 'Parent', app.ImageAxes, ...
    % 'XData', [1 app.ImageAxes.Position(3)], ...
    % 'YData', [1 app.ImageAxes.Position(4)]);
    %         box(ImageAxes,"on")
        if size(Image, 3)==1
            ImageDisp.ImageSource = repmat(Image, [1 1 3]);
        elseif size(Image, 3)==3
            ImageDisp.ImageSource = Image;
        end
       
        end
        function updateDisplayedImage(app)
            if isempty(app.SegmentedImages)
                return;
            end
    
            fixIMGDisplay(app, app.SegmentedImages{app.CurrentImageIndex}, app.ImageDisp);
            data = app.AnalysisData{app.CurrentImageIndex};
            app.Number_of_Cells_Field.Value = size(data, 1);
            app.Area_Field.Value = mean(data.Area);
            app.updatePlot();
        end


        function updatePlot(app)
       % Plotting depending on toggles
            if isempty(app.statsPerImage)
                return;
            end

            % Gather stats
            if  strcmp(app.ToggleCumulative.Value, 'On')
                % Cumulative across all images
                combinedStats = vertcat(app.statsPerImage{:});
            else
                % Just show first image's stats
                combinedStats = app.statsPerImage{1};
            end

            % Decide plot type
            if  strcmp(app.TogglePlotType.Value, 'Off')
                % Histogram view
                featureNameX = app.XDropDown.Value;
                featureValues = [combinedStats.(featureName)];
                histogram(app.HistogramAxes, featureValues);
                xlabel(app.HistogramAxes, featureNameX);
                ylabel(app.HistogramAxes, 'Frequency');
                title(app.HistogramAxes, ['Histogram of ', featureName]);
            else
                % Scatter plot view
                featureX = app.XDropDown.Value;
                featureY = app.YDropDown.Value;

                xValues = [combinedStats.(featureX)];
                yValues = [combinedStats.(featureY)];

                scatter(app.HistogramAxes, xValues, yValues, 'filled');
                xlabel(app.HistogramAxes, featureX);
                ylabel(app.HistogramAxes, featureY);
                title(app.HistogramAxes, [featureY, ' vs ', featureX]);
            end
        end
    
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fixIMGDisplay(app, ones(520, 740)*0.9, app.ImageDisp);
        end

        % Callback function: ImageDisp, UploadImagesButton
        function UploadImage(app, event)
            [files, path] = uigetfile({'*.png;*.jpg;*.tif'}, 'Select Images', 'MultiSelect', 'on');
            if isequal(files, 0)
                errordlg('No images selected!', 'Error');
                return;
            end

            if ~iscell(files)
                files = {files};
            end

            app.Images = {};
            for k = 1:length(files)
                img = rescale(imread(fullfile(path, files{k})));
                if size(img, 3) == 3
                    img = mean(img, 3);
                end
                app.Images{end+1} = img;
            end

            app.CurrentImageIndex = 1;
            app.SegmentedImages = {};
            app.Masks = {};
            app.Boxes = {};
            app.Labels = {};
            app.Scores = {};
            app.AnalysisData = {};
            fixIMGDisplay(app, app.Images{1}, app.ImageDisp);
        
        
        end

        % Button pushed function: Segment_Button
        function Segment(app, event)
        if isempty(app.Images)
        errordlg('Please upload an image first!', 'Error');
        return;
        end 

    % Add MRCNNsrc path (Make sure this path is correct)
      addpath(genpath('/Users/dhrumilrajpara/Documents/MATLAB/Year 4/Group Design Project/4th-Year-Image-Processing-Project/4th-Year-Image-Processing-Project/MRCNNsrc'));

    % Load pre-trained CNN model if not already loaded
    if isempty(app.net)
        try
           app.net = load('/Users/dhrumilrajpara/Documents/MATLAB/Year 4/Group Design Project/4th-Year-Image-Processing-Project/4th-Year-Image-Processing-Project/netnew.mat','net'); % Load the network file
            app.net = app.net.net; % Extract the network object
            app.net.OverlapThreshold = 0.1;
        catch
           % errordlg('Error loading CNN model. Check file path!', 'Error');
            return;
        end
    end


    % Perform cell segmentation
    try
      %  app.SiimulationProgress.Value = 0.1;
      % pause(0.1);
      img = app.Images{app.CurrentImageIndex};  % or app.Images{1} if you don't have a toggle
    if ~isa(img, 'cell') && ~isa(img, 'uint16')
    img = im2single(img);
end  
      
      [app.Masks app.Labels app.Scores app.Boxes] = segmentCells(app.net, app.Images, "NumstrongestRegions",Inf, MinSize=[4 4], MaxSize=[80 80],SelectStrongest=true, SegmentThreshold=0.1);
        %app.SimulationProgress.Value = 0.7;
         %       pause(0.1);

        if (isempty(app.Masks))
            app.SegmentedImages = app.Images;
        else
            app.SegmentedImages = insertObjectMask(app.Images, app.Masks,Color=lines(size(app.Masks, 3)) );
        end
        % Display the segmented image with masks
        fixIMGDisplay(app, app.SegmentedImages, app.ImageDisp);
        
        app.AnalysisData = ExtractFeatures(app.Images, app.Masks, app.Boxes, 1);

        % Update the cell count field
        app.Number_of_Cells_Field.Value = size(app.AnalysisData, 1);
        app.Area_Field.Value = mean(app.AnalysisData.Area);
      %  app.SimulationProgress.Value = 1.0;
      %         pause(0.2);
    catch ME
        errordlg(['Segmentation error: ', ME.message], 'Error');
    end
        PlotHistogram(app,1);

        
        end

        % Value changed function: XDropDown
        function PlotHistogram(app, event)
            value = app.XDropDown.Value;
            if isempty(app.AnalysisData)
                return;
            end
            % Extract the selected feature's values
            featureData = app.AnalysisData.(value);
            
            % Plot histogram
            histogram(featureData, 'Parent',app.HistogramAxes);
            xlabel(app.HistogramAxes, value);
            ylabel(app.HistogramAxes, 'Frequency');

        end

        % Callback function
        function PreviousButtonPushed(app, event)
              if app.CurrentImageIndex > 1
                app.CurrentImageIndex = app.CurrentImageIndex - 1;
                app.updateDisplayedImage();
              end
        
        end

        % Callback function
        function NextButtonPushed(app, event)
           if app.CurrentImageIndex < length(app.SegmentedImages)
                app.CurrentImageIndex = app.CurrentImageIndex + 1;
                app.updateDisplayedImage();
           end
        
        end

        % Callback function
        function ToggleSwitchValueChanged(app, event)
            app.updatePlot();
        end

        % Callback function
        function XAxisDropdownValueChanged(app, event)
            app.updatePlot();
        end

        % Callback function
        function YAxisDropdownValueChanged(app, event)
            app.updatePlot();
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'turbo');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramAxes = uiaxes(app.UIFigure);
            xlabel(app.HistogramAxes, 'Area')
            ylabel(app.HistogramAxes, 'Perimeter')
            app.HistogramAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.HistogramAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.HistogramAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            colormap(app.HistogramAxes, 'turbo')
            app.UploadImagesButton = uibutton(app.UIFigure, 'push');
            app.UploadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImage, true);
            app.UploadImagesButton.Text = 'Select Images';
            app.Settings_Button = uibutton(app.UIFigure, 'push');
            app.Settings_Button.Icon = fullfile(pathToMLAPP, 'download.png');
            app.Settings_Button.Text = '';
            app.Help_Button = uibutton(app.UIFigure, 'push');
            app.Help_Button.Text = '?';
            app.Segment_Button = uibutton(app.UIFigure, 'push');
            app.Segment_Button.ButtonPushedFcn = createCallbackFcn(app, @Segment, true);
            app.Segment_Button.Text = 'Segment';
            app.Number_of_Cells_Text = uilabel(app.UIFigure);
            app.Number_of_Cells_Text.Text = 'Number of Cells ';
            app.Number_of_Cells_Field = uieditfield(app.UIFigure, 'numeric');
            app.YDropDown = uidropdown(app.UIFigure);
            app.YDropDown.Items = {'Frequency', 'Area', 'Perimeter', 'STDeviation', 'AspectRatio', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.YDropDown.Value = 'Area';
            app.FeatureLabel = uilabel(app.UIFigure);
            app.FeatureLabel.Text = 'X ';
            app.XDropDown = uidropdown(app.UIFigure);
            app.XDropDown.Items = {'Area', 'Perimeter', 'STDeviation', 'AspectRatio', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogram, true);
            app.XDropDown.Value = 'Area';
            app.Credit = uihyperlink(app.UIFigure);
            app.Credit.URL = 'https://github.com/TechAvi-eng/4th-Year-Image-Processing-Project';
            app.Credit.Text = 'Link to Git';
            app.ExportData_Button = uibutton(app.UIFigure, 'push');
            app.ExportData_Button.Icon = fullfile(pathToMLAPP, 'export-icon-2048x2048-g2mr2v94.png');
            app.ExportData_Button.Text = '';
            app.ImageDisp = uiimage(app.UIFigure);
            app.ImageDisp.ImageClickedFcn = createCallbackFcn(app, @UploadImage, true);
            app.SimulationProgress = uisimprogress(app.UIFigure);
            app.SimulationProgress.ShowElapsedTime = 'off';
            app.SimulationProgress.Visible = 'off';
            app.ModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeSwitch.Items = {'All', 'Individual'};
            app.ModeSwitch.Value = 'All';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Text = 'Next';
            app.YFeatureLabel = uilabel(app.UIFigure);
            app.YFeatureLabel.Text = 'Y';
            app.PlotFeatures = uilabel(app.UIFigure);
            app.PlotFeatures.Text = 'Plot Features:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end